classdef predicted_root_mass < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        CalculationtabTab        matlab.ui.container.Tab
        B9RuthlabpredictedrootmassappLabel  matlab.ui.control.Label
        Button_15                matlab.ui.control.Button
        EditField                matlab.ui.control.EditField
        SOLVEButton              matlab.ui.control.Button
        Button                   matlab.ui.control.Button
        Button_2                 matlab.ui.control.Button
        Button_3                 matlab.ui.control.Button
        Button_4                 matlab.ui.control.Button
        Button_5                 matlab.ui.control.Button
        Button_6                 matlab.ui.control.Button
        Button_7                 matlab.ui.control.Button
        Button_8                 matlab.ui.control.Button
        Button_9                 matlab.ui.control.Button
        Button_10                matlab.ui.control.Button
        Button_11                matlab.ui.control.Button
        Button_12                matlab.ui.control.Button
        DELButton                matlab.ui.control.Button
        ValueofaEditFieldLabel   matlab.ui.control.Label
        ValueofaEditField        matlab.ui.control.EditField
        ExportButton             matlab.ui.control.Button
        Button_13                matlab.ui.control.Button
        Button_14                matlab.ui.control.Button
        ACButton                 matlab.ui.control.Button
        ValueofbEditFieldLabel   matlab.ui.control.Label
        ValueofbEditField        matlab.ui.control.EditField
        AddtotableButton         matlab.ui.control.Button
        ReadtableButton          matlab.ui.control.Button
        stand_nameDropDownLabel  matlab.ui.control.Label
        stand_nameDropDown       matlab.ui.control.DropDown
        CreatedbyKhoiTranLabel   matlab.ui.control.Label
        plotDropDownLabel        matlab.ui.control.Label
        plotDropDown             matlab.ui.control.DropDown
        subplotDropDownLabel     matlab.ui.control.Label
        subplotDropDown          matlab.ui.control.DropDown
        Table                    matlab.ui.container.Tab
        UITable                  matlab.ui.control.Table
        EnterrownumberwanttodeleteEditFieldLabel  matlab.ui.control.Label
        EnterrownumberwanttodeleteEditField  matlab.ui.control.EditField
        DeleteCheckBox           matlab.ui.control.CheckBox
        GraphTab                 matlab.ui.container.Tab
        UIAxes                   matlab.ui.control.UIAxes
        PlotButton               matlab.ui.control.Button
        StandnameDropDownLabel   matlab.ui.control.Label
        StandnameDropDown        matlab.ui.control.DropDown
        plotDropDown_2Label      matlab.ui.control.Label
        plotDropDown_2           matlab.ui.control.DropDown
        ExportButton_2           matlab.ui.control.Button
        Button_16                matlab.ui.control.Button
        UITable2                 matlab.ui.control.Table
    end

    
    properties (Access = private)
        memory = [];
    end
    
    properties (Access = public)
        a_constant
        b_constant
        stand_name
        plot
        subplot
        time_recorded_data
        t
    end
    

    methods (Access = private)

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            message_screen = uifigure;
            message = {'How to enter value', 'Start with "[" then enter first number, click "," then second number, click "]" and click SOLVE', 'Use "." for decimal number'};
            uialert(message_screen, message, 'info', 'Icon', 'info');
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.memory = [app.memory '0'];
            app.EditField.Value = app.memory;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.memory = [app.memory '1'];
            app.EditField.Value = app.memory;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.memory = [app.memory '2'];
            app.EditField.Value = app.memory;
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.memory = [app.memory '3'];
            app.EditField.Value = app.memory;
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.memory = [app.memory '4'];
            app.EditField.Value = app.memory;
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.memory = [app.memory '5'];
            app.EditField.Value = app.memory;
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            app.memory = [app.memory '6'];
            app.EditField.Value = app.memory;
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            app.memory = [app.memory '7'];
            app.EditField.Value = app.memory;
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            app.memory = [app.memory '8'];
            app.EditField.Value = app.memory;
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            app.memory = [app.memory '9'];
            app.EditField.Value = app.memory;
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            app.memory = [app.memory '.'];
            app.EditField.Value = app.memory;
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            app.memory = [app.memory '['];
            app.EditField.Value = app.memory;
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            app.memory = [app.memory ']'];
            app.EditField.Value = app.memory;
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            app.memory = [app.memory ','];
            app.EditField.Value = app.memory;
        end

        % Button pushed function: DELButton
        function DELButtonPushed(app, event)
            app.memory(end) = [];
            app.EditField.Value = app.memory;
        end

        % Button pushed function: ACButton
        function ACButtonPushed(app, event)
            app.memory = '';
            app.EditField.Value = app.memory;
        end

        % Button pushed function: SOLVEButton
        function SOLVEButtonPushed(app, event)
            %             syms a b;
            num_array = str2num(app.memory);
            first_num_for_eqn = num_array(1,1);
            second_num_for_eqn = num_array(1,2);
            %             first_eqn = a*log10(10)+b == first_num_for_eqn;
            %             second_eqn = a*log10(20)+b == second_num_for_eqn;
            a = (second_num_for_eqn - first_num_for_eqn) / (log10(20) - log10(10));
            b = first_num_for_eqn - a*log10(10);
            a = num2str(a);
            b = num2str(b);
            %             solution = solve([first_eqn, second_eqn], [a,b]);            
%             convert_step_1 = struct2cell(solution);
%             convert_step_2 = [convert_step_1{:}];
%             convert_step_3_a = double(convert_step_2(1,1));
%             convert_step_3_b = double(convert_step_2(1,2));
%             convert_step_4_a = num2str(convert_step_3_a);
%             convert_step_4_b = num2str(convert_step_3_b);
            app.ValueofaEditField.Value = a;
            app.ValueofbEditField.Value = b;
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            [filename, folder] = uiputfile("*.xls*");
            writetable(app.t,fullfile(folder,filename))
            %             table_form = app.t;
            %             table_form.Properties.VariableNames = {'a_constant' 'b_constant' 'stand_name' 'plot' 'subplot' 'time_recorded_data'};
            %             writetable(table_form, 'test data file.xlsx');
        end

        % Button pushed function: AddtotableButton
        function AddtotableButtonPushed(app, event)
            a = str2double(app.ValueofaEditField.Value);
            b = str2double(app.ValueofbEditField.Value);
            app.stand_name = app.stand_nameDropDown.Value;
            app.plot = num2str(app.plotDropDown.Value);
            app.subplot = num2str(app.subplotDropDown.Value);
            app.time_recorded_data = convertCharsToStrings(datestr(datetime('now','TimeZone','local','Format','eeee, MMMM d, yyyy h:mm a')));
            new_row = table(a, b, app.stand_name, app.plot, app.subplot, app.time_recorded_data);
            new_row.Properties.VariableNames = {'a_constant' 'b_constant' 'stand_name' 'plot' 'subplot' 'time_recorded_data'};
            app.t.Properties.VariableNames = {'a_constant' 'b_constant' 'stand_name' 'plot' 'subplot' 'time_recorded_data'};
            app.UITable.Data = [app.t; new_row];
            app.t = app.UITable.Data; %This step is really important because it updates the table with the new value we just added.
        end

        % Button pushed function: ReadtableButton
        function ReadtableButtonPushed(app, event)
            filename = uigetfile({'*.xls'; '*.xlsx'}, 'Pick an excel file');
            app.t = readtable(filename);
            app.UITable.Data = app.t;
        end

        % Value changed function: DeleteCheckBox
        function DeleteCheckBoxValueChanged(app, event)
            value = app.DeleteCheckBox.Value;
            if (value == 1)
                app.EnterrownumberwanttodeleteEditField.Enable = 'on';
            else
                app.EnterrownumberwanttodeleteEditField.Enable = 'off';
            end
        end

        % Value changed function: EnterrownumberwanttodeleteEditField
        function EnterrownumberwanttodeleteEditFieldValueChanged(app, event)
            value = app.EnterrownumberwanttodeleteEditField.Value;
            convert_array = str2num(value);
            table_deleted = app.t;
            disp(table_deleted);
            table_deleted(convert_array, : ) = [];
            app.UITable.Data = table_deleted;
            app.t = app.UITable.Data;
            new_table = app.t;
            writetable(new_table, 'test data file.xlsx');
            % 2 ways to solve: 1 is to save the file into new excel file, delete the old excel file and rename the new one to old one.
            % 2 you can add another data point and it will automatically save your new excel file
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            table_form = app.UITable.Data;
            stand = app.StandnameDropDown.Value;
            plot_name = str2num(app.plotDropDown_2.Value);
            table_form.stand_name = string(table_form.stand_name);
            table_form.plot = str2double(string(table_form.plot));
            filter_by_stand = table_form(table_form.stand_name == stand,:);
            filter_by_plot = filter_by_stand(filter_by_stand.plot == plot_name,:);
            %Basically, I filter
            num_rows = height(filter_by_plot);
            root_mass_array_10 = [];
            root_mass_array_20 = [];
            root_mass_array_30 = [];
            root_mass_array_40 = [];
            for i = 1 : num_rows
                array_10 = filter_by_plot.a_constant(i) * log10(10) + filter_by_plot.b_constant(i);
                array_20 = filter_by_plot.a_constant(i) * log10(20) + filter_by_plot.b_constant(i);
                array_30 = filter_by_plot.a_constant(i) * log10(30) + filter_by_plot.b_constant(i);
                array_40 = filter_by_plot.a_constant(i) * log10(40) + filter_by_plot.b_constant(i);
                root_mass_array_10 = [root_mass_array_10 array_10];
                root_mass_array_20 = [root_mass_array_20 array_20];
                root_mass_array_30 = [root_mass_array_30 array_30];
                root_mass_array_40 = [root_mass_array_40 array_40];
            end
            average_root_10 = sum(root_mass_array_10) / num_rows;
            average_root_20 = sum(root_mass_array_20) / num_rows;
            average_root_30 = sum(root_mass_array_30) / num_rows;
            average_root_40 = sum(root_mass_array_40) / num_rows;
            y1 = [average_root_10 average_root_20 average_root_30 average_root_40];
            y1_reshape = reshape(y1, [4,1]);
            x = 10:10:40;
            x_reshape = reshape(x, [4,1]);
            new_table_plot = table(x_reshape, y1_reshape);
            new_table_plot.Properties.VariableNames = {'Time' 'Root_mass'};
            app.UITable2.Data = [new_table_plot];
            hFig = app.UIAxes;
            plot(app.UIAxes, x, y1);
            hold(app.UIAxes);
            y2 = [0.01, 0.25, 1.3, 8.9];
            plot(x,y1);
            %             hold 'on';
            %             y2 = [0.01, 0.25, 1.3, 8.9];
            %             plot(x,y2)
            % line 288 to 291 is used only if you want to graphs 2 plots on 1 figure
            xlswritefig       %it will automatic open your excel file for you
            % Please note to put the fucking xlswritefig function in the same folder with your fucking data
            % I wasted fucking 1 hour to understand that
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
            message_screen = uifigure;
            message = {'How to plot graphs', 'Specify stand name. Then specify plot, you can either produce graphs for each plot or do average value for all plots'};
            uialert(message_screen, message, 'info', 'Icon', 'info');
        end

        % Button pushed function: ExportButton_2
        function ExportButton_2Pushed(app, event)
            hFig = app.UIAxes;
            [filename, folder] = uiputfile("*.xls*");
            xlswritefig
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CalculationtabTab = uitab(app.TabGroup);
            app.CalculationtabTab.Title = 'Calculation tab';
            app.B9RuthlabpredictedrootmassappLabel = uilabel(app.CalculationtabTab);
            app.B9RuthlabpredictedrootmassappLabel.Text = 'B9 Ruth lab predicted root mass app';
            app.Button_15 = uibutton(app.CalculationtabTab, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Text = '?';
            app.EditField = uieditfield(app.CalculationtabTab, 'text');
            app.EditField.Editable = 'off';
            app.SOLVEButton = uibutton(app.CalculationtabTab, 'push');
            app.SOLVEButton.ButtonPushedFcn = createCallbackFcn(app, @SOLVEButtonPushed, true);
            app.SOLVEButton.Text = 'SOLVE';
            app.Button = uibutton(app.CalculationtabTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '0';
            app.Button_2 = uibutton(app.CalculationtabTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '1';
            app.Button_3 = uibutton(app.CalculationtabTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '2';
            app.Button_4 = uibutton(app.CalculationtabTab, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '3';
            app.Button_5 = uibutton(app.CalculationtabTab, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '4';
            app.Button_6 = uibutton(app.CalculationtabTab, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '5';
            app.Button_7 = uibutton(app.CalculationtabTab, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '6';
            app.Button_8 = uibutton(app.CalculationtabTab, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '7';
            app.Button_9 = uibutton(app.CalculationtabTab, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '8';
            app.Button_10 = uibutton(app.CalculationtabTab, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '9';
            app.Button_11 = uibutton(app.CalculationtabTab, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '.';
            app.Button_12 = uibutton(app.CalculationtabTab, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '[';
            app.DELButton = uibutton(app.CalculationtabTab, 'push');
            app.DELButton.ButtonPushedFcn = createCallbackFcn(app, @DELButtonPushed, true);
            app.DELButton.Text = 'DEL';
            app.ValueofaEditFieldLabel = uilabel(app.CalculationtabTab);
            app.ValueofaEditFieldLabel.Text = 'Value of a';
            app.ValueofaEditField = uieditfield(app.CalculationtabTab, 'text');
            app.ValueofaEditField.Editable = 'off';
            app.ExportButton = uibutton(app.CalculationtabTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.Button_13 = uibutton(app.CalculationtabTab, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = ',';
            app.Button_14 = uibutton(app.CalculationtabTab, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = ']';
            app.ACButton = uibutton(app.CalculationtabTab, 'push');
            app.ACButton.ButtonPushedFcn = createCallbackFcn(app, @ACButtonPushed, true);
            app.ACButton.Text = 'AC';
            app.ValueofbEditFieldLabel = uilabel(app.CalculationtabTab);
            app.ValueofbEditFieldLabel.Text = 'Value of b';
            app.ValueofbEditField = uieditfield(app.CalculationtabTab, 'text');
            app.ValueofbEditField.Editable = 'off';
            app.AddtotableButton = uibutton(app.CalculationtabTab, 'push');
            app.AddtotableButton.ButtonPushedFcn = createCallbackFcn(app, @AddtotableButtonPushed, true);
            app.AddtotableButton.Text = 'Add to table';
            app.ReadtableButton = uibutton(app.CalculationtabTab, 'push');
            app.ReadtableButton.ButtonPushedFcn = createCallbackFcn(app, @ReadtableButtonPushed, true);
            app.ReadtableButton.Text = 'Read table';
            app.stand_nameDropDownLabel = uilabel(app.CalculationtabTab);
            app.stand_nameDropDownLabel.Text = 'stand_name';
            app.stand_nameDropDown = uidropdown(app.CalculationtabTab);
            app.stand_nameDropDown.Items = {'C1', 'C2', 'C3', 'C4', 'C5', 'C6', 'C7', 'C8', 'C9'};
            app.stand_nameDropDown.Value = 'C1';
            app.CreatedbyKhoiTranLabel = uilabel(app.CalculationtabTab);
            app.CreatedbyKhoiTranLabel.Text = 'Created by Khoi Tran';
            app.plotDropDownLabel = uilabel(app.CalculationtabTab);
            app.plotDropDownLabel.Text = 'plot';
            app.plotDropDown = uidropdown(app.CalculationtabTab);
            app.plotDropDown.Items = {'1', '2', '3', '4'};
            app.plotDropDown.Value = '1';
            app.subplotDropDownLabel = uilabel(app.CalculationtabTab);
            app.subplotDropDownLabel.Text = 'subplot';
            app.subplotDropDown = uidropdown(app.CalculationtabTab);
            app.subplotDropDown.Items = {'1', '2', '3a', '3b', '3c', '3d', '4', '5', '6', '7', '7a', '8', '9'};
            app.subplotDropDown.Value = '1';
            app.Table = uitab(app.TabGroup);
            app.Table.Title = 'Data table';
            app.UITable = uitable(app.Table);
            app.UITable.ColumnName = {'a_constant'; 'b_constant'; 'stand_name'; 'plot'; 'subplot'; 'time_recorded_data'};
            app.UITable.RowName = {};
            app.EnterrownumberwanttodeleteEditFieldLabel = uilabel(app.Table);
            app.EnterrownumberwanttodeleteEditFieldLabel.Text = 'Enter row number want to delete';
            app.EnterrownumberwanttodeleteEditField = uieditfield(app.Table, 'text');
            app.EnterrownumberwanttodeleteEditField.ValueChangedFcn = createCallbackFcn(app, @EnterrownumberwanttodeleteEditFieldValueChanged, true);
            app.DeleteCheckBox = uicheckbox(app.Table);
            app.DeleteCheckBox.ValueChangedFcn = createCallbackFcn(app, @DeleteCheckBoxValueChanged, true);
            app.DeleteCheckBox.Text = 'Delete?';
            app.GraphTab = uitab(app.TabGroup);
            app.GraphTab.Title = 'Graph';
            app.UIAxes = uiaxes(app.GraphTab);
            title(app.UIAxes, 'Average predicted root mass')
            xlabel(app.UIAxes, 'Time collected (mins)')
            ylabel(app.UIAxes, 'Root mass (g)')
            app.PlotButton = uibutton(app.GraphTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.StandnameDropDownLabel = uilabel(app.GraphTab);
            app.StandnameDropDownLabel.Text = 'Stand name';
            app.StandnameDropDown = uidropdown(app.GraphTab);
            app.StandnameDropDown.Items = {'C1', 'C2', 'C3', 'C4', 'C5', 'C6', 'C7', 'C8', 'C9'};
            app.StandnameDropDown.Value = 'C1';
            app.plotDropDown_2Label = uilabel(app.GraphTab);
            app.plotDropDown_2Label.Text = 'plot';
            app.plotDropDown_2 = uidropdown(app.GraphTab);
            app.plotDropDown_2.Items = {'1', '2', '3', '4'};
            app.plotDropDown_2.Value = '1';
            app.ExportButton_2 = uibutton(app.GraphTab, 'push');
            app.ExportButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportButton_2Pushed, true);
            app.ExportButton_2.Text = 'Export';
            app.Button_16 = uibutton(app.GraphTab, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Text = '?';
            app.UITable2 = uitable(app.GraphTab);
            app.UITable2.ColumnName = {'Time'; 'Root mass'};
            app.UITable2.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = predicted_root_mass

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end